classdef Ristretto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure            matlab.ui.Figure
        MainFigureGridLayout  matlab.ui.container.GridLayout
        TabGroup              matlab.ui.container.TabGroup
        RawFilesTab           matlab.ui.container.Tab
        RFTGridLayout         matlab.ui.container.GridLayout
        RFTTable              matlab.ui.control.Table
        RFTFolderGridLayout   matlab.ui.container.GridLayout
        EditFieldLabel        matlab.ui.control.Label
        RFTFolderEditField    matlab.ui.control.EditField
        RFTFolderButton       matlab.ui.control.Button
        RFTButtonsGridLayout  matlab.ui.container.GridLayout
        RFTButtonConvert      matlab.ui.control.Button
        RTFButtonLoad         matlab.ui.control.Button
        LoadedLinesTab        matlab.ui.container.Tab
        UIAxes                matlab.ui.control.UIAxes
        RFTContextMenu        matlab.ui.container.ContextMenu
        SelectallMenu         matlab.ui.container.Menu
        DeselectallMenu       matlab.ui.container.Menu
        InverseselectionMenu  matlab.ui.container.Menu
    end

    
    properties (Access = public)
        % general properties
        CoffeeCodeRootFolder % Description
        FDataVersion
        FData = [];
        
        % raw data properties
        rawDataFilesList = [];
        idxConverted
        idxLoaded
        
        % configuration
        datagramSource = 'X8';
        ellips = '';
        tmproj = '';
        
    end
    
    
    methods (Access = private)
        
        % Update Raw Files Tab
        function updateRawFilesTable(app,keepSelection)
            
            % save list of files selected
            if ~isempty(app.RFTTable.Data)
                idxFilesSelected = cell2mat(app.RFTTable.Data(:,1));
            end
            
            % list raw_files in search path
            rawDataFolder = app.RFTFolderEditField.Value;
            app.rawDataFilesList = CFF_list_raw_files_in_dir(rawDataFolder);
            
            % check which are already converted
            [app.idxConverted,idxFDataUpToDate] = CFF_are_raw_files_converted(app.rawDataFilesList);
            
            % files with outdated version
            if any(idxFDataUpToDate==0)
                % set converted files with wrong version as "not converted"
                app.idxConverted(idxFDataUpToDate==0) = 0;
                % throw warning
                warning('One or several files in this folder have been previously converted using an outdated version of Espresso. They will require reconversion and thus show as NOT converted.');
            end
            
            % check which are currently loaded
            app.idxLoaded = CFF_are_raw_files_loaded(app.rawDataFilesList, app.FData);
            
            % prepare array
            n_rawfiles = numel(app.rawDataFilesList);
            [disp_folder, filename, ext] = CFF_fileparts_as_cell(CFF_onerawfileonly(app.rawDataFilesList));
            disp_files = strcat(filename, ext);
            for ii = 1:n_rawfiles
                if iscell(app.rawDataFilesList{ii})
                    % paired file
                    disp_files{ii} = strcat(disp_files{ii}, ' (paired)');
                end
            end
            new_entry = [num2cell(false(n_rawfiles, 1)), disp_files, disp_folder];
            
            % update table data
            app.RFTTable.Data = new_entry;
            
            % set files colors depending on status
            removeStyle(app.RFTTable);
            if ~isempty(new_entry)
                app.RFTTable.Visible = 'off';
                idx2subs = @(idx) cat(2,find([idx;0]),2.*ones(numel(find(idx)),1)); % function to turn files idx to table subscript indices
                styleNotConverted = uistyle('FontColor',[0.5 0.5 0.5]); % gray
                addStyle(app.RFTTable,styleNotConverted,'cell',idx2subs(~app.idxConverted));
                styleConvertedNotLoaded = uistyle('FontWeight','bold','FontColor','k'); % black
                addStyle(app.RFTTable,styleConvertedNotLoaded,'cell',idx2subs(app.idxConverted&~app.idxLoaded));
                styleLoaded = uistyle('FontWeight','bold','FontColor',[0.4660 0.6740 0.1880]); % green
                addStyle(app.RFTTable,styleLoaded,'cell',idx2subs(app.idxConverted&app.idxLoaded));
                app.RFTTable.Visible = 'on';
            end

            % re-select files selected
            if exist('keepSelection','var') && keepSelection==1 && ~isempty(idxFilesSelected)
                app.RFTTable.Visible = 'off';
                app.RFTTable.Data(idxFilesSelected,1) = {true};
                app.RFTTable.Visible = 'on';
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Add Ristretto folder to path
            RistrettoRootFolder = fileparts(which('Ristretto'));
            addpath(genpath(RistrettoRootFolder));
            
            % Add Coffee code to path
            app.CoffeeCodeRootFolder = 'C:\Users\Schimel_Alexandre\Code\MATLAB\Espresso\toolboxes\coffee';
            if isfolder(app.CoffeeCodeRootFolder)
                % Add to path
                addpath(genpath(app.CoffeeCodeRootFolder));
                app.FDataVersion = CFF_get_current_fData_version();
            else
                % To-do XXX1: Throw an alert to find manually a suitable
                % coffee folder
            end
            
            % Resize and reposition figure according to monitor's dimension
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.8;
            height = screenHeight*0.8;
            drawnow;
            app.MainFigure.Position = [left bottom width height];
            
            % Main figure icon
            app.MainFigure.Icon = 'Espresso.png';
            
            % Set default folder location
            app.RFTFolderEditField.Value = cd;
            
            % Initialize Raw Files table
            app.updateRawFilesTable();
            
        end

        % Button pushed function: RFTFolderButton
        function RFTFolderButtonPushed(app, event)
            % Open folder selection dialog box
            currentFolder = app.RFTFolderEditField.Value;
            newFolder = uigetdir(currentFolder,'Select folder of raw data files');
            if newFolder ~= 0
                % Update value of the FolderEditField
                app.RFTFolderEditField.Value = newFolder;
                % Update Raw Files Table contents
                app.updateRawFilesTable();
            end
        end

        % Value changed function: RFTFolderEditField
        function RFTFolderEditFieldValueChanged(app, event)
            % Check if value is an existing folder
            requestedFolder = app.RFTFolderEditField.Value;
            if ~isfolder(requestedFolder)
                % Error dialog box
                uialert(app.MainFigure,'Folder not found','Invalid Folder','CloseFcn','uiresume(gcbf)');
                uiwait(app.MainFigure)
                % Reset previous field value
                app.RFTFolderEditField.Value = event.PreviousValue;
            else
                % Update Raw Files Table contents
                app.updateRawFilesTable();
            end
        end

        % Button pushed function: RFTButtonConvert
        function RFTButtonConvertPushed(app, event)
            % Get idx of selected files
            idxFilesToConvert = cell2mat(app.RFTTable.Data(:,1));
            % Default value to be overwritten if relevant
            forceReconvert = 0;
            % Prompt if some files already converted
            if any(idxFilesToConvert.*app.idxConverted)
                N = sum(idxFilesToConvert.*app.idxConverted);
                if N == 1
                    questDlgTxt = sprintf('1 file for which conversion was requested is already converted.\n\nDo you want to re-convert it?');
                else
                    questDlgTxt = sprintf('%i files for which conversion was requested are already converted.\n\nDo you want to re-convert them?',N);
                end
                answer = questdlg(questDlgTxt, ...
                    'Raw Files Conversion', ...
                    'Yes','No','Cancel','Cancel');
                switch answer
                    case 'Yes'
                        forceReconvert = 1;
                    case 'No'
                        forceReconvert = 0;
                    case 'Cancel'
                        % abort converting
                        return
                end
            end
            % Get filenames
            rawFilesList = app.rawDataFilesList(idxFilesToConvert);
            % Convert files
            CFF_convert_raw_files(rawFilesList,...
                'conversionType','seafloor',...
                'saveFDataToDrive',1,...
                'forceReconvert',forceReconvert,...
                'outputFData',0,...
                'abortOnError',0,...
                'convertEvenIfDtgrmsMissing',0,...
                'dr_sub',1,...
                'db_sub',1,...
                'comms','multilines');
            
            % Update Raw Files Table contents
            app.updateRawFilesTable(1);
        end

        % Menu selected function: SelectallMenu
        function SelectallMenuSelected(app, event)
            app.RFTTable.Data(:,1) = cellfun(@(x) {true}, app.RFTTable.Data(:,1));
        end

        % Menu selected function: DeselectallMenu
        function DeselectallMenuSelected(app, event)
            app.RFTTable.Data(:,1) = cellfun(@(x) {false}, app.RFTTable.Data(:,1));
        end

        % Menu selected function: InverseselectionMenu
        function InverseselectionMenuSelected(app, event)
            app.RFTTable.Data(:,1) = cellfun(@(x) {~x}, app.RFTTable.Data(:,1));
        end

        % Button pushed function: RTFButtonLoad
        function RTFButtonLoadPushed(app, event)
            % Get idx of selected files
            idxFilesSelected = cell2mat(app.RFTTable.Data(:,1));
            idxFilesToLoad = idxFilesSelected & ~app.idxLoaded;
            % Idx of files requested but not converted yet
            idxNeedConversionFirst = idxFilesToLoad & ~app.idxConverted;
            % Prompt if some files not already converted
            if any(idxNeedConversionFirst)
                N = sum(idxNeedConversionFirst);
                if N == 1
                    questDlgTxt = sprintf('1 file for which loading was requested has not been converted yet.\n\nDo you want to convert it and load it?');
                else
                    questDlgTxt = sprintf('%i files for which loading was requested have not been converted yet.\n\nDo you want to convert them and load them?',N);
                end
                answer = questdlg(questDlgTxt, ...
                    'Converted Files Loading', ...
                    'Yes','No','Cancel','Cancel');
                switch answer
                    case 'Yes'
                        % Convert those files
                        CFF_convert_raw_files(app.rawDataFilesList(idxNeedConversionFirst),...
                            'conversionType','seafloor',...
                            'saveFDataToDrive',1,...
                            'forceReconvert',0,...
                            'outputFData',0,...
                            'abortOnError',0,...
                            'convertEvenIfDtgrmsMissing',0,...
                            'dr_sub',1,...
                            'db_sub',1,...
                            'comms','multilines');
                        % Update Raw Files Table contents
                        app.updateRawFilesTable(1);
                    case 'No'
                        % Remove non-converted files from loading list
                        idxFilesToLoad = logical(idxFilesToLoad.*app.idxConverted);
                    case 'Cancel'
                        % abort loading
                        return
                end
            end
            % Get filenames
            toLoadFilesList = app.rawDataFilesList(idxFilesToLoad);
            if isempty(toLoadFilesList)
                return
            end
            % Geoprocess files
            fData_new = CFF_geoprocess_files(toLoadFilesList,...
                'datagramSource',app.datagramSource,...
                'ellips',app.ellips,...
                'tmproj',app.tmproj,...
                'saveFDataToDrive',0,...
                'outputFData',1,...
                'abortOnError',0,...
                'comms','multilines');
            % by default, CFF_geoprocess_files outputs a struct for a single file. Turn
            % to cell array if a single file was loaded
            if isstruct(fData_new)
                fData_new = {fData_new};
            end
            % update app's geoprocessing parameters
            if isempty(app.tmproj)
                app.ellips = fData_new{1}.MET_ellips;
                app.tmproj = fData_new{1}.MET_tmproj;
            end
            if isempty(app.datagramSource)
                app.datagramSource = fData_new{1}.MET_datagramSource;
            end
            % fix fData paths if necessary
            fData_new = CFF_fix_fData_paths(fData_new, toLoadFilesList);
            % Time-tag the individual fData structs
            for iF = 1:numel(fData_new)
                fData_new{iF}.ID = str2double(datestr(now,'yyyymmddHHMMSSFFF'));
                pause(1e-2); % pause here to ensure unique IDs
            end
            % add new fData to old one
            app.FData = [app.FData,fData_new];
            % Update Raw Files Table contents
            app.updateRawFilesTable(1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'Ristretto';
            app.MainFigureGridLayout = uigridlayout(app.MainFigure);
            app.TabGroup = uitabgroup(app.MainFigureGridLayout);
            app.RawFilesTab = uitab(app.TabGroup);
            app.RawFilesTab.Title = 'Raw files';
            app.RFTGridLayout = uigridlayout(app.RawFilesTab);
            app.RFTTable = uitable(app.RFTGridLayout);
            app.RFTTable.ColumnName = {''; 'File'; 'Folder'};
            app.RFTTable.RowName = {};
            app.RFTTable.ColumnSortable = [false false false];
            app.RFTTable.ColumnEditable = [true false false];
            app.RFTFolderGridLayout = uigridlayout(app.RFTGridLayout);
            app.EditFieldLabel = uilabel(app.RFTFolderGridLayout);
            app.EditFieldLabel.Text = 'Edit Field';
            app.RFTFolderEditField = uieditfield(app.RFTFolderGridLayout, 'text');
            app.RFTFolderEditField.ValueChangedFcn = createCallbackFcn(app, @RFTFolderEditFieldValueChanged, true);
            app.RFTFolderButton = uibutton(app.RFTFolderGridLayout, 'push');
            app.RFTFolderButton.ButtonPushedFcn = createCallbackFcn(app, @RFTFolderButtonPushed, true);
            app.RFTFolderButton.Icon = 'foldericon.gif';
            app.RFTFolderButton.Text = '';
            app.RFTButtonsGridLayout = uigridlayout(app.RFTGridLayout);
            app.RFTButtonConvert = uibutton(app.RFTButtonsGridLayout, 'push');
            app.RFTButtonConvert.ButtonPushedFcn = createCallbackFcn(app, @RFTButtonConvertPushed, true);
            app.RFTButtonConvert.Text = 'Convert';
            app.RTFButtonLoad = uibutton(app.RFTButtonsGridLayout, 'push');
            app.RTFButtonLoad.ButtonPushedFcn = createCallbackFcn(app, @RTFButtonLoadPushed, true);
            app.RTFButtonLoad.Text = 'Load';
            app.LoadedLinesTab = uitab(app.TabGroup);
            app.LoadedLinesTab.Title = 'Loaded lines';
            app.UIAxes = uiaxes(app.MainFigureGridLayout);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTickLabel = '';
            app.UIAxes.YTickLabel = '';
            app.RFTContextMenu = uicontextmenu(app.MainFigure);
            % Assign app.RFTContextMenu
            app.RFTTable.ContextMenu = app.RFTContextMenu;
            app.SelectallMenu = uimenu(app.RFTContextMenu);
            app.SelectallMenu.MenuSelectedFcn = createCallbackFcn(app, @SelectallMenuSelected, true);
            app.SelectallMenu.Text = 'Select all';
            app.DeselectallMenu = uimenu(app.RFTContextMenu);
            app.DeselectallMenu.MenuSelectedFcn = createCallbackFcn(app, @DeselectallMenuSelected, true);
            app.DeselectallMenu.Text = 'Deselect all';
            app.InverseselectionMenu = uimenu(app.RFTContextMenu);
            app.InverseselectionMenu.MenuSelectedFcn = createCallbackFcn(app, @InverseselectionMenuSelected, true);
            app.InverseselectionMenu.Text = 'Inverse selection';
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Ristretto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end